classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        ExpressionLabelBasic    matlab.ui.control.Label
        TabGroup                matlab.ui.container.TabGroup
        Tab                     matlab.ui.container.Tab
        GridLayout              matlab.ui.container.GridLayout
        Button_1                matlab.ui.control.Button
        Button_Sqrt             matlab.ui.control.Button
        Button_Minus            matlab.ui.control.Button
        Button_Power            matlab.ui.control.Button
        Button_Modulus          matlab.ui.control.Button
        Button_Square           matlab.ui.control.Button
        Button_Closebar         matlab.ui.control.Button
        Button_Openbar          matlab.ui.control.Button
        Button_Backspace        matlab.ui.control.Button
        Button_Comma            matlab.ui.control.Button
        Button_Division         matlab.ui.control.Button
        Button_Multiply         matlab.ui.control.Button
        Button_Plus             matlab.ui.control.Button
        Button_Equals           matlab.ui.control.Button
        CButton                 matlab.ui.control.Button
        Button_10               matlab.ui.control.Button
        Button_9                matlab.ui.control.Button
        Button_8                matlab.ui.control.Button
        Button_7                matlab.ui.control.Button
        Button_6                matlab.ui.control.Button
        Button_5                matlab.ui.control.Button
        Button_4                matlab.ui.control.Button
        Button_3                matlab.ui.control.Button
        Button_2                matlab.ui.control.Button
        Tab_6                   matlab.ui.container.Tab
        GridLayout_2            matlab.ui.container.GridLayout
        Button_F_4              matlab.ui.control.Button
        Button_20               matlab.ui.control.Button
        Button_pi_2             matlab.ui.control.Button
        Button_Minus_5          matlab.ui.control.Button
        Button_c_2              matlab.ui.control.Button
        Button_g_2              matlab.ui.control.Button
        Button_e_2              matlab.ui.control.Button
        Button_Closebar_3       matlab.ui.control.Button
        Button_Openbar_3        matlab.ui.control.Button
        Button_Backspace_5      matlab.ui.control.Button
        Button_Comma_5          matlab.ui.control.Button
        Button_Division_2       matlab.ui.control.Button
        Button_Multiply_2       matlab.ui.control.Button
        Button_Plus_2           matlab.ui.control.Button
        Button_Equals_3         matlab.ui.control.Button
        CButton_5               matlab.ui.control.Button
        Button_19               matlab.ui.control.Button
        Button_18               matlab.ui.control.Button
        Button_17               matlab.ui.control.Button
        Button_16               matlab.ui.control.Button
        Button_15               matlab.ui.control.Button
        Button_14               matlab.ui.control.Button
        Button_13               matlab.ui.control.Button
        Button_12               matlab.ui.control.Button
        Button_11               matlab.ui.control.Button
        Tab_2                   matlab.ui.container.Tab
        GridLayout2             matlab.ui.container.GridLayout
        Button_Minus_2          matlab.ui.control.Button
        axbxc0Button            matlab.ui.control.Button
        logxButton              matlab.ui.control.Button
        lnxButton               matlab.ui.control.Button
        exButton                matlab.ui.control.Button
        GCDButton               matlab.ui.control.Button
        LCMButton               matlab.ui.control.Button
        DivisorsxButton         matlab.ui.control.Button
        PrimeFactorsButton      matlab.ui.control.Button
        Button_Factorial        matlab.ui.control.Button
        Button_Backspace_2      matlab.ui.control.Button
        Button_Closebar_2       matlab.ui.control.Button
        Button_Openbar_2        matlab.ui.control.Button
        Button_Comma_2          matlab.ui.control.Button
        CButton_2               matlab.ui.control.Button
        Button_Equals_2         matlab.ui.control.Button
        Button_2_0              matlab.ui.control.Button
        Button_1_9              matlab.ui.control.Button
        Button_1_8              matlab.ui.control.Button
        Button_1_7              matlab.ui.control.Button
        Button_1_6              matlab.ui.control.Button
        Button_1_5              matlab.ui.control.Button
        Button_1_4              matlab.ui.control.Button
        Button_1_3              matlab.ui.control.Button
        Button_1_2              matlab.ui.control.Button
        Button_1_1              matlab.ui.control.Button
        Tab_3                   matlab.ui.container.Tab
        GridLayout3             matlab.ui.container.GridLayout
        atanxButton             matlab.ui.control.Button
        acscxButton             matlab.ui.control.Button
        Button_Minus_3          matlab.ui.control.Button
        asecxButton             matlab.ui.control.Button
        acotxButton             matlab.ui.control.Button
        acosxButton             matlab.ui.control.Button
        asinxButton             matlab.ui.control.Button
        cscxButton              matlab.ui.control.Button
        secxButton              matlab.ui.control.Button
        cotxButton              matlab.ui.control.Button
        tanxButton              matlab.ui.control.Button
        cosxButton              matlab.ui.control.Button
        sinxButton              matlab.ui.control.Button
        Button_Comma_3          matlab.ui.control.Button
        Button_2__0             matlab.ui.control.Button
        CButton_3               matlab.ui.control.Button
        Button_2_9              matlab.ui.control.Button
        Button_2_8              matlab.ui.control.Button
        Button_2_7              matlab.ui.control.Button
        Button_2_6              matlab.ui.control.Button
        Button_2_5              matlab.ui.control.Button
        Button_2_4              matlab.ui.control.Button
        Button_2_3              matlab.ui.control.Button
        Button_2_2              matlab.ui.control.Button
        Button_2_1              matlab.ui.control.Button
        Button_Backspace_3      matlab.ui.control.Button
        ExpressionLabelBasic_3  matlab.ui.control.Label
        Tab_4                   matlab.ui.container.Tab
        GridLayout4             matlab.ui.container.GridLayout
        acschxButton            matlab.ui.control.Button
        Button_Minus_4          matlab.ui.control.Button
        asechxButton            matlab.ui.control.Button
        acothxButton            matlab.ui.control.Button
        atanhxButton            matlab.ui.control.Button
        acoshxButton            matlab.ui.control.Button
        asinhxButton            matlab.ui.control.Button
        cschxButton             matlab.ui.control.Button
        sechxButton             matlab.ui.control.Button
        cothxButton             matlab.ui.control.Button
        tanhxButton             matlab.ui.control.Button
        coshxButton             matlab.ui.control.Button
        sinhxButton             matlab.ui.control.Button
        Button_Comma_4          matlab.ui.control.Button
        Button_3_0              matlab.ui.control.Button
        CButton_4               matlab.ui.control.Button
        Button_3_9              matlab.ui.control.Button
        Button_3_8              matlab.ui.control.Button
        Button_3_7              matlab.ui.control.Button
        Button_3_6              matlab.ui.control.Button
        Button_3_5              matlab.ui.control.Button
        Button_3_4              matlab.ui.control.Button
        Button_3_3              matlab.ui.control.Button
        Button_3_2              matlab.ui.control.Button
        Button_3_1              matlab.ui.control.Button
        Button_Backspace_4      matlab.ui.control.Button
        ExpressionLabelBasic_4  matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button_1, Button_1_1, Button_20, 
        % ...and 2 other components
        function Button1Pushed(app, event)
            app.ExpressionLabelBasic.Text = strcat(app.ExpressionLabelBasic.Text, '1');
        end

        % Button pushed function: Button_11, Button_1_2, Button_2, 
        % ...and 2 other components
        function Button2Pushed(app, event)
                app.ExpressionLabelBasic.Text = strcat(app.ExpressionLabelBasic.Text, '2');
        end

        % Button pushed function: Button_12, Button_1_3, Button_2_3, 
        % ...and 2 other components
        function Button3Pushed(app, event)
                app.ExpressionLabelBasic.Text = strcat(app.ExpressionLabelBasic.Text, '3');
        end

        % Button pushed function: Button_13, Button_1_4, Button_2_4, 
        % ...and 2 other components
        function Button4Pushed(app, event)
            app.ExpressionLabelBasic.Text = strcat(app.ExpressionLabelBasic.Text, '4');
        end

        % Button pushed function: Button_14, Button_1_5, Button_2_5, 
        % ...and 2 other components
        function Button5Pushed(app, event)
            app.ExpressionLabelBasic.Text = strcat(app.ExpressionLabelBasic.Text, '5');
        end

        % Button pushed function: Button_15, Button_1_6, Button_2_6, 
        % ...and 2 other components
        function Button6Pushed(app, event)
            app.ExpressionLabelBasic.Text = strcat(app.ExpressionLabelBasic.Text, '6');
        end

        % Button pushed function: Button_16, Button_1_7, Button_2_7, 
        % ...and 2 other components
        function Button7Pushed(app, event)
            app.ExpressionLabelBasic.Text = strcat(app.ExpressionLabelBasic.Text, '7');
        end

        % Button pushed function: Button_17, Button_1_8, Button_2_8, 
        % ...and 2 other components
        function Button8Pushed(app, event)
            app.ExpressionLabelBasic.Text = strcat(app.ExpressionLabelBasic.Text, '8');
        end

        % Button pushed function: Button_18, Button_1_9, Button_2_9, 
        % ...and 2 other components
        function Button9Pushed(app, event)
            app.ExpressionLabelBasic.Text = strcat(app.ExpressionLabelBasic.Text, '9');
        end

        % Button pushed function: Button_10, Button_19, Button_2_0, 
        % ...and 2 other components
        function Button0Pushed(app, event)
            app.ExpressionLabelBasic.Text = strcat(app.ExpressionLabelBasic.Text, '0');
        end

        % Button pushed function: Button_Plus, Button_Plus_2
        function ButtonPlusPushed(app, event)
            app.ExpressionLabelBasic.Text = strcat(app.ExpressionLabelBasic.Text, ' + ');
        end

        % Button pushed function: Button_Minus, Button_Minus_2, 
        % ...and 3 other components
        function ButtonMinusPushed(app, event)
            app.ExpressionLabelBasic.Text = strcat(app.ExpressionLabelBasic.Text, '-');
        end

        % Button pushed function: Button_Multiply, Button_Multiply_2
        function ButtonMultiplyPushed(app, event)
            app.ExpressionLabelBasic.Text = strcat(app.ExpressionLabelBasic.Text, '*');
        end

        % Button pushed function: Button_Division, Button_Division_2
        function ButtonDividePushed(app, event)
            app.ExpressionLabelBasic.Text = strcat(app.ExpressionLabelBasic.Text, '/');
        end

        % Button pushed function: Button_Comma, Button_Comma_2, 
        % ...and 3 other components
        function ButtonCommaPushed(app, event)
            app.ExpressionLabelBasic.Text = strcat(app.ExpressionLabelBasic.Text, ', ');
        end

        % Button pushed function: CButton, CButton_2, CButton_3, 
        % ...and 2 other components
        function ButtonClearPushed(app, event)
            app.ExpressionLabelBasic.Text = '';
        end

        % Button pushed function: Button_Backspace, Button_Backspace_2, 
        % ...and 3 other components
        function Button_BackspacePushed(app, event)
            text = app.ExpressionLabelBasic.Text;
            if ~isempty(text)
        app.ExpressionLabelBasic.Text = extractBefore(text, strlength(text));
            end
        end

        % Button pushed function: Button_Equals, Button_Equals_2, 
        % ...and 1 other component
        function Button_EqualsPushed(app, event)
            try
        expr = app.ExpressionLabelBasic.Text;
        expr = strrep(expr, ',', '.');  % Για υποστήριξη δεκαδικών

        % Αντικατάσταση όλων των "mod" με συνάρτηση mod() χωρίς loop
        expr = regexprep(expr, '(?<=\d)\s*mod\s*(?=\d)', '@');

        % Τώρα κάνουμε ένα-ένα replace του '@' με σωστή μορφή mod()
        expr = regexprep(expr, '(\d+)\s*@\s*(\d+)', 'mod($1,$2)');

        % Υπολογισμός της τελικής έκφρασης
        result = eval(expr);
        app.ExpressionLabelBasic.Text = num2str(result);
    catch
        app.ExpressionLabelBasic.Text = 'Σφάλμα';
    end
        end

        % Button pushed function: Button_Openbar, Button_Openbar_2, 
        % ...and 1 other component
        function ButtonOpenbarPushed(app, event)
            app.ExpressionLabelBasic.Text = strcat(app.ExpressionLabelBasic.Text, '(');
        end

        % Button pushed function: Button_Closebar, Button_Closebar_2, 
        % ...and 1 other component
        function ButtonClosebarPushed(app, event)
            app.ExpressionLabelBasic.Text = strcat(app.ExpressionLabelBasic.Text, ')');
        end

        % Button pushed function: Button_Square
        function ButtonSquarePushed(app, event)
            app.ExpressionLabelBasic.Text = strcat(app.ExpressionLabelBasic.Text, '^2');
        end

        % Button pushed function: Button_Power
        function ButtonPowerPushed(app, event)
            app.ExpressionLabelBasic.Text = strcat(app.ExpressionLabelBasic.Text, '^');
        end

        % Button pushed function: Button_Modulus
        function Button_ModulusPushed(app, event)
            app.ExpressionLabelBasic.Text = strcat(app.ExpressionLabelBasic.Text, ' mod ');
        end

        % Button pushed function: Button_Sqrt
        function ButtonSqrtPushed(app, event)
            app.ExpressionLabelBasic.Text = strcat(app.ExpressionLabelBasic.Text, ' sqrt(');
        end

        % Callback function
        function Button_FactorialPushed(app, event)
            x = str2double(app.InputEditField.Value);  % Αν έχεις input πεδίο με αυτό το όνομα
    if isnan(x) || x < 0 || mod(x,1) ~= 0
        app.ExpressionLabelBasic.Text = 'Δώσε μη αρνητικό ακέραιο αριθμό';
        return;
    end
    result = factorial(x);
    app.ExpressionLabelBasic.Text = ['Αποτέλεσμα: ' num2str(result)];
        end

        % Button pushed function: PrimeFactorsButton
        function PrimeFactorsButtonPushed(app, event)
            expr = app.ExpressionLabelBasic.Text;
    x = str2double(expr);  % μετατροπή του περιεχομένου του label σε αριθμό

    if isnan(x) || x < 2 || mod(x,1) ~= 0
        app.ExpressionLabelBasic.Text = 'Δώσε ακέραιο αριθμό >= 2 ';
        return;
    end

    pf = factor(x);
    pfText = strjoin(string(pf), ' × ');
    app.ExpressionLabelBasic.Text = ['Πρώτοι παράγοντες: ' pfText];
        end

        % Button pushed function: DivisorsxButton
        function DivisorsxButtonPushed(app, event)
        
    x = str2double(app.ExpressionLabelBasic.Text);

    if isnan(x) || x < 1 || mod(x,1) ~= 0
        app.ExpressionLabelBasic.Text = 'Δώσε θετικό ακέραιο αριθμό';
        return;
    end

    % Χειροκίνητος υπολογισμός διαιρετών
    divs = [];
    for i = 1:x
        if mod(x, i) == 0
            divs(end+1) = i; %#ok<AGROW>
        end
    end

    divsText = strjoin(string(divs), ', ');
    app.ExpressionLabelBasic.Text = ['Διαιρέτες: ' divsText];


        end

        % Button pushed function: LCMButton
        function LCMButtonPushed(app, event)
               expr = app.ExpressionLabelBasic.Text;

    % Αφαίρεσε παρενθέσεις αν υπάρχουν
    expr = strrep(expr, '(', '');
    expr = strrep(expr, ')', '');

    % Διάσπασε τη συμβολοσειρά με βάση το κόμμα
    parts = strsplit(expr, ',');

    if numel(parts) ~= 2
        app.ExpressionLabelBasic.Text = 'Δώσε δύο τιμές σε μορφή (x,y)';
        return;
    end

    x = str2double(strtrim(parts{1}));
    y = str2double(strtrim(parts{2}));

    if isnan(x) || isnan(y) || x <= 0 || y <= 0 || mod(x,1) ~= 0 || mod(y,1) ~= 0
        app.ExpressionLabelBasic.Text = 'Δώσε δύο θετικούς ακέραιους αριθμούς';
        return;
    end

    result = lcm(x, y);
    app.ExpressionLabelBasic.Text = ['Ε.Κ.Π: ' num2str(result)];
        end

        % Button pushed function: GCDButton
        function GCDButtonPushed(app, event)
             expr = app.ExpressionLabelBasic.Text;

    % Αφαίρεσε τα παρενθετικά αν υπάρχουν
    expr = strrep(expr, '(', '');
    expr = strrep(expr, ')', '');

    % Διάσπασε την έκφραση με βάση το κόμμα
    parts = strsplit(expr, ',');

    if numel(parts) ~= 2
        app.ExpressionLabelBasic.Text = 'Δώσε δύο τιμές σε μορφή (x,y)';
        return;
    end

    x = str2double(strtrim(parts{1}));
    y = str2double(strtrim(parts{2}));

    if isnan(x) || isnan(y) || x <= 0 || y <= 0 || mod(x,1) ~= 0 || mod(y,1) ~= 0
        app.ExpressionLabelBasic.Text = 'Δώσε δύο θετικούς ακέραιους αριθμούς';
        return;
    end

    result = gcd(x, y);
    app.ExpressionLabelBasic.Text = ['Μ.Κ.Δ: ' num2str(result)];
        end

        % Button pushed function: exButton
        function exButtonPushed(app, event)
            x = str2double(app.ExpressionLabelBasic.Text);

    if isnan(x)
        app.ExpressionLabelBasic.Text = 'Δώσε αριθμό';
        return;
    end

    result = exp(x);  % exp(x) = e^x
    app.ExpressionLabelBasic.Text = ['e^' num2str(x) ' = ' num2str(result)];
        end

        % Button pushed function: Button_Factorial
        function Button_FactorialPushed2(app, event)
           x = str2double(app.ExpressionLabelBasic.Text);

    if isnan(x) || x < 0 || mod(x,1) ~= 0
        app.ExpressionLabelBasic.Text = 'Δώσε μη αρνητικό ακέραιο αριθμό';
        return;
    end

    result = factorial(x);
    app.ExpressionLabelBasic.Text = [num2str(x) '! = ' num2str(result)];
        end

        % Button pushed function: logxButton
        function logxButtonPushed(app, event)
             x = str2double(app.ExpressionLabelBasic.Text);

    if isnan(x) || x <= 0
        app.ExpressionLabelBasic.Text = 'Δώσε θετικό αριθμό';
        return;
    end

    result = log10(x);  % log10 = λογάριθμος βάση 10
    app.ExpressionLabelBasic.Text = ['log₁₀(' num2str(x) ') = ' num2str(result)];
        end

        % Button pushed function: axbxc0Button
        function axbxc0ButtonPushed(app, event)
        expr = app.ExpressionLabelBasic.Text;
    expr = strrep(expr, '(', '');
    expr = strrep(expr, ')', '');
    parts = strsplit(expr, ',');

    if numel(parts) ~= 3
        app.ExpressionLabelBasic.Text = 'Δώσε τρεις τιμές στη μορφή (a,b,c)';
        return;
    end

    a = str2double(strtrim(parts{1}));
    b = str2double(strtrim(parts{2}));
    c = str2double(strtrim(parts{3}));

    if isnan(a) || isnan(b) || isnan(c) || a == 0
        app.ExpressionLabelBasic.Text = 'Δώσε έγκυρες τιμές με a ≠ 0';
        return;
    end

    D = b^2 - 4*a*c;

    % Εσωτερική "mini"-function για καθαρή εμφάνιση αριθμών
    function s = clean(x)
        if mod(x,1) == 0
            s = sprintf('%d', x);  % ακέραιος
        else
            s = sprintf('%.2f', x);  % μέχρι 2 δεκαδικά
        end
    end

    % Υπολογισμός λύσεων
    if D > 0
        x1 = (-b + sqrt(D)) / (2*a);
        x2 = (-b - sqrt(D)) / (2*a);
        app.ExpressionLabelBasic.Text = ...
            ['Δ=' clean(D) ', x1=' clean(x1) ', x2=' clean(x2)];
    elseif D == 0
        x = -b / (2*a);
        app.ExpressionLabelBasic.Text = ...
            ['Δ=0, x=' clean(x)];
    else
        realPart = -b / (2*a);
        imagPart = sqrt(abs(D)) / (2*a);
        app.ExpressionLabelBasic.Text = ...
            ['Δ<0, x1=' clean(realPart) '+' clean(imagPart) 'i, x2=' ...
             clean(realPart) '-' clean(imagPart) 'i'];
    end
        end

        % Button pushed function: lnxButton
        function lnxButtonPushed(app, event)
            expr = app.ExpressionLabelBasic.Text;
    x = str2double(expr);

    if isnan(x) || x <= 0
        app.ExpressionLabelBasic.Text = 'Δώσε θετικό αριθμό';
        return;
    end

    result = log(x);  % ln(x) στο MATLAB
    app.ExpressionLabelBasic.Text = ['ln(' num2str(x) ') = ' num2str(result)];
        end

        % Button pushed function: sinxButton
        function sinxButtonPushed(app, event)
            expr = app.ExpressionLabelBasic.Text;
    x = str2double(expr);

    if isnan(x)
        app.ExpressionLabelBasic.Text = 'Δώσε αριθμό σε μοίρες';
        return;
    end

    x_rad = deg2rad(x);
    result = sin(x_rad);

    app.ExpressionLabelBasic.Text = ['sin(' num2str(x) '°) = ' num2str(result)];
        end

        % Button pushed function: cosxButton
        function cosxButtonPushed(app, event)
            expr = app.ExpressionLabelBasic.Text;
    x = str2double(expr);

    if isnan(x)
        app.ExpressionLabelBasic.Text = 'Δώσε αριθμό σε μοίρες';
        return;
    end

    x_rad = deg2rad(x);
    result = cos(x_rad);

    app.ExpressionLabelBasic.Text = ['cos(' num2str(x) '°) = ' num2str(result)];
        end

        % Button pushed function: tanxButton
        function tanxButtonPushed(app, event)
            expr = app.ExpressionLabelBasic.Text;
    x = str2double(expr);

    if isnan(x)
        app.ExpressionLabelBasic.Text = 'Δώσε αριθμό σε μοίρες';
        return;
    end

    x_rad = deg2rad(x);
    result = tan(x_rad);

    app.ExpressionLabelBasic.Text = ['tan(' num2str(x) '°) = ' num2str(result)];
        end

        % Button pushed function: cotxButton
        function cotxButtonPushed(app, event)
            expr = app.ExpressionLabelBasic.Text;
    x = str2double(expr);

    if isnan(x)
        app.ExpressionLabelBasic.Text = 'Δώσε αριθμό σε μοίρες';
        return;
    end

    x_rad = deg2rad(x);
    tanx = tan(x_rad);
    if tanx == 0
        app.ExpressionLabelBasic.Text = 'cot undefined (tan=0)';
        return;
    end

    result = 1 / tanx;
    app.ExpressionLabelBasic.Text = ['cot(' num2str(x) '°) = ' num2str(result)];
        end

        % Button pushed function: secxButton
        function secxButtonPushed(app, event)
            expr = app.ExpressionLabelBasic.Text;
    x = str2double(expr);

    if isnan(x)
        app.ExpressionLabelBasic.Text = 'Δώσε αριθμό σε μοίρες';
        return;
    end

    x_rad = deg2rad(x);
    cosx = cos(x_rad);
    if cosx == 0
        app.ExpressionLabelBasic.Text = 'sec undefined (cos=0)';
        return;
    end

    result = 1 / cosx;
    app.ExpressionLabelBasic.Text = ['sec(' num2str(x) '°) = ' num2str(result)];
        end

        % Button pushed function: cscxButton
        function cscxButtonPushed(app, event)
            expr = app.ExpressionLabelBasic.Text;
    x = str2double(expr);

    if isnan(x)
        app.ExpressionLabelBasic.Text = 'Δώσε αριθμό σε μοίρες';
        return;
    end

    x_rad = deg2rad(x);
    sinx = sin(x_rad);
    if sinx == 0
        app.ExpressionLabelBasic.Text = 'csc undefined (sin=0)';
        return;
    end

    result = 1 / sinx;
    app.ExpressionLabelBasic.Text = ['csc(' num2str(x) '°) = ' num2str(result)];
        end

        % Button pushed function: asinxButton
        function asinxButtonPushed(app, event)
            expr = app.ExpressionLabelBasic.Text;
expr = strrep(expr, ',', '.');  % <-- αυτό λύνει το θέμα
x = str2double(expr);

if isnan(x) || abs(x) > 1
    app.ExpressionLabelBasic.Text = 'Δώσε τιμή μεταξύ -1 και 1';
    return;
end

result = asin(x);  % radians
deg = rad2deg(result);
app.ExpressionLabelBasic.Text = ['arcsin(' num2str(x) ') = ' num2str(deg) '°'];
        end

        % Button pushed function: acosxButton
        function acosxButtonPushed(app, event)
            expr = app.ExpressionLabelBasic.Text;
            expr = strrep(expr, ',', '.');  % 🔁 κόμμα → τελεία
    x = str2double(expr);

    if isnan(x) || abs(x) > 1
        app.ExpressionLabelBasic.Text = 'Δώσε τιμή μεταξύ -1 και 1';
        return;
    end

    result = acos(x);
    deg = rad2deg(result);
    app.ExpressionLabelBasic.Text = ['arccos(' num2str(x) ') = ' num2str(deg) '°'];
        end

        % Button pushed function: acotxButton
        function acotxButtonPushed(app, event)
            expr = app.ExpressionLabelBasic.Text;
    x = str2double(expr);

    if isnan(x)
        app.ExpressionLabelBasic.Text = 'Δώσε αριθμό';
        return;
    end

    result = atan(1/x);  % acot(x) = atan(1/x)
    deg = rad2deg(result);
    app.ExpressionLabelBasic.Text = ['arccot(' num2str(x) ') = ' num2str(deg) '°'];
        end

        % Button pushed function: asecxButton
        function asecxButtonPushed(app, event)
            expr = app.ExpressionLabelBasic.Text;
    x = str2double(expr);

    if isnan(x) || abs(x) < 1
        app.ExpressionLabelBasic.Text = 'Δώσε |x| ≥ 1';
        return;
    end

    result = acos(1/x);
    deg = rad2deg(result);
    app.ExpressionLabelBasic.Text = ['arcsec(' num2str(x) ') = ' num2str(deg) '°'];
        end

        % Button pushed function: acscxButton
        function acscxButtonPushed(app, event)
            expr = app.ExpressionLabelBasic.Text;
    x = str2double(expr);

    if isnan(x) || abs(x) < 1
        app.ExpressionLabelBasic.Text = 'Δώσε |x| ≥ 1';
        return;
    end

    result = asin(1/x);
    deg = rad2deg(result);
    app.ExpressionLabelBasic.Text = ['arccsc(' num2str(x) ') = ' num2str(deg) '°'];
        end

        % Button pushed function: sinhxButton
        function sinhxButtonPushed(app, event)
            expr = app.ExpressionLabelBasic.Text;
    expr = strrep(expr, ',', '.');
    x = str2double(expr);

    if isnan(x)
        app.ExpressionLabelBasic.Text = 'Δώσε αριθμό';
        return;
    end

    result = sinh(x);
    app.ExpressionLabelBasic.Text = ['sinh(' num2str(x) ') = ' num2str(result)];
        end

        % Button pushed function: coshxButton
        function coshxButtonPushed(app, event)
            expr = app.ExpressionLabelBasic.Text;
    expr = strrep(expr, ',', '.');
    x = str2double(expr);

    if isnan(x)
        app.ExpressionLabelBasic.Text = 'Δώσε αριθμό';
        return;
    end

    result = cosh(x);
    app.ExpressionLabelBasic.Text = ['cosh(' num2str(x) ') = ' num2str(result)];
        end

        % Button pushed function: tanhxButton
        function tanhxButtonPushed(app, event)
            expr = app.ExpressionLabelBasic.Text;
    expr = strrep(expr, ',', '.');
    x = str2double(expr);

    if isnan(x)
        app.ExpressionLabelBasic.Text = 'Δώσε αριθμό';
        return;
    end

    result = tanh(x);
    app.ExpressionLabelBasic.Text = ['tanh(' num2str(x) ') = ' num2str(result)];
        end

        % Button pushed function: cothxButton
        function cothxButtonPushed(app, event)
            expr = app.ExpressionLabelBasic.Text;
    expr = strrep(expr, ',', '.');
    x = str2double(expr);

    if isnan(x) || x == 0
        app.ExpressionLabelBasic.Text = 'Δώσε αριθμό ≠ 0';
        return;
    end

    result = 1 / tanh(x);
    app.ExpressionLabelBasic.Text = ['coth(' num2str(x) ') = ' num2str(result)];
        end

        % Button pushed function: sechxButton
        function sechxButtonPushed(app, event)
            expr = app.ExpressionLabelBasic.Text;
    expr = strrep(expr, ',', '.');
    x = str2double(expr);

    if isnan(x)
        app.ExpressionLabelBasic.Text = 'Δώσε αριθμό';
        return;
    end

    result = 1 / cosh(x);
    app.ExpressionLabelBasic.Text = ['sech(' num2str(x) ') = ' num2str(result)];
        end

        % Button pushed function: cschxButton
        function cschxButtonPushed(app, event)
            expr = app.ExpressionLabelBasic.Text;
    expr = strrep(expr, ',', '.');
    x = str2double(expr);

    if isnan(x) || x == 0
        app.ExpressionLabelBasic.Text = 'Δώσε αριθμό ≠ 0';
        return;
    end

    result = 1 / sinh(x);
    app.ExpressionLabelBasic.Text = ['csch(' num2str(x) ') = ' num2str(result)];
        end

        % Button pushed function: asinhxButton
        function asinhxButtonPushed(app, event)
            expr = app.ExpressionLabelBasic.Text;
    expr = strrep(expr, ',', '.');
    x = str2double(expr);

    if isnan(x)
        app.ExpressionLabelBasic.Text = 'Δώσε αριθμό';
        return;
    end

    result = asinh(x);
    app.ExpressionLabelBasic.Text = ['arsinh(' num2str(x) ') = ' num2str(result)];
        end

        % Button pushed function: acoshxButton
        function acoshxButtonPushed(app, event)
            expr = app.ExpressionLabelBasic.Text;
    expr = strrep(expr, ',', '.');
    x = str2double(expr);

    if isnan(x) || x < 1
        app.ExpressionLabelBasic.Text = 'Δώσε αριθμό ≥ 1';
        return;
    end

    result = acosh(x);
    app.ExpressionLabelBasic.Text = ['arcosh(' num2str(x) ') = ' num2str(result)];
        end

        % Button pushed function: atanhxButton
        function atanhxButtonPushed(app, event)
            expr = app.ExpressionLabelBasic.Text;
    expr = strrep(expr, ',', '.');
    x = str2double(expr);

    if isnan(x) || abs(x) >= 1
        app.ExpressionLabelBasic.Text = 'Δώσε τιμή μεταξύ -1 και 1 (όχι άκρα)';
        return;
    end

    result = atanh(x);
    app.ExpressionLabelBasic.Text = ['artanh(' num2str(x) ') = ' num2str(result)];
        end

        % Button pushed function: acothxButton
        function acothxButtonPushed(app, event)
            expr = app.ExpressionLabelBasic.Text;
    expr = strrep(expr, ',', '.');
    x = str2double(expr);

    if isnan(x) || abs(x) <= 1
        app.ExpressionLabelBasic.Text = 'Δώσε |x| > 1';
        return;
    end

    result = atanh(1/x);  % acoth(x) = atanh(1/x)
    app.ExpressionLabelBasic.Text = ['arcoth(' num2str(x) ') = ' num2str(result)];
        end

        % Button pushed function: asechxButton
        function asechxButtonPushed(app, event)
            expr = app.ExpressionLabelBasic.Text;
    expr = strrep(expr, ',', '.');
    x = str2double(expr);

    if isnan(x) || x <= 0 || x > 1
        app.ExpressionLabelBasic.Text = 'Δώσε τιμή στο διάστημα (0, 1]';
        return;
    end

    result = acosh(1/x);
    app.ExpressionLabelBasic.Text = ['arsech(' num2str(x) ') = ' num2str(result)];
        end

        % Button pushed function: acschxButton
        function acschxButtonPushed(app, event)
            expr = app.ExpressionLabelBasic.Text;
    expr = strrep(expr, ',', '.');
    x = str2double(expr);

    if isnan(x) || x == 0
        app.ExpressionLabelBasic.Text = 'Δώσε αριθμό ≠ 0';
        return;
    end

    result = asinh(1/x);
    app.ExpressionLabelBasic.Text = ['arcsch(' num2str(x) ') = ' num2str(result)];
        end

        % Button pushed function: atanxButton
        function atanxButtonPushed(app, event)
            expr = app.ExpressionLabelBasic.Text;         
    expr = strrep(expr, ',', '.');                  
    x = str2double(expr);

    if isnan(x)
        app.ExpressionLabelBasic.Text = 'Δώσε αριθμό';
        return;
    end

    result = atan(x);                 
    deg = rad2deg(result);           

    app.ExpressionLabelBasic.Text = ['arctan(' num2str(x) ') = ' sprintf('%.4f', deg) '°'];
        end

        % Button pushed function: Button_pi_2
        function Button_pi_2Pushed(app, event)
                app.ExpressionLabelBasic.Text = strcat(app.ExpressionLabelBasic.Text, '3.141592653589793');
        end

        % Button pushed function: Button_F_4
        function Button_F_4Pushed(app, event)
            app.ExpressionLabelBasic.Text = strcat(app.ExpressionLabelBasic.Text, '96485.33212')
        end

        % Callback function
        function Button_k_3Pushed(app, event)
            app.ExpressionLabelBasic.Text = strcat(app.ExpressionLabelBasic.Text, '1.380649e-23')
        end

        % Button pushed function: Button_c_2
        function Button_c_2Pushed(app, event)
           app.ExpressionLabelBasic.Text = strcat(app.ExpressionLabelBasic.Text, '299792458')
        end

        % Button pushed function: Button_g_2
        function Button_g_2Pushed(app, event)
            app.ExpressionLabelBasic.Text = strcat(app.ExpressionLabelBasic.Text, '9.80665')
        end

        % Button pushed function: Button_e_2
        function Button_e_2Pushed(app, event)
             app.ExpressionLabelBasic.Text = strcat(app.ExpressionLabelBasic.Text, '2.718281828459045')
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = 'Βασικές';
            app.GridLayout = uigridlayout(app.Tab);
            app.Button_2 = uibutton(app.GridLayout, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button2Pushed, true);
            app.Button_2.Text = '2';
            app.Button_3 = uibutton(app.GridLayout, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button3Pushed, true);
            app.Button_3.Text = '3';
            app.Button_4 = uibutton(app.GridLayout, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button4Pushed, true);
            app.Button_4.Text = '4';
            app.Button_5 = uibutton(app.GridLayout, 'push');
            app.Button_5.ButtonPushedFcn = createCallbackFcn(app, @Button5Pushed, true);
            app.Button_5.Text = '5';
            app.Button_6 = uibutton(app.GridLayout, 'push');
            app.Button_6.ButtonPushedFcn = createCallbackFcn(app, @Button6Pushed, true);
            app.Button_6.Text = '6';
            app.Button_7 = uibutton(app.GridLayout, 'push');
            app.Button_7.ButtonPushedFcn = createCallbackFcn(app, @Button7Pushed, true);
            app.Button_7.Text = '7';
            app.Button_8 = uibutton(app.GridLayout, 'push');
            app.Button_8.ButtonPushedFcn = createCallbackFcn(app, @Button8Pushed, true);
            app.Button_8.Text = '8';
            app.Button_9 = uibutton(app.GridLayout, 'push');
            app.Button_9.ButtonPushedFcn = createCallbackFcn(app, @Button9Pushed, true);
            app.Button_9.Text = '9';
            app.Button_10 = uibutton(app.GridLayout, 'push');
            app.Button_10.ButtonPushedFcn = createCallbackFcn(app, @Button0Pushed, true);
            app.Button_10.Text = '0';
            app.CButton = uibutton(app.GridLayout, 'push');
            app.CButton.ButtonPushedFcn = createCallbackFcn(app, @ButtonClearPushed, true);
            app.CButton.Text = 'C';
            app.Button_Equals = uibutton(app.GridLayout, 'push');
            app.Button_Equals.ButtonPushedFcn = createCallbackFcn(app, @Button_EqualsPushed, true);
            app.Button_Equals.Text = '=';
            app.Button_Plus = uibutton(app.GridLayout, 'push');
            app.Button_Plus.ButtonPushedFcn = createCallbackFcn(app, @ButtonPlusPushed, true);
            app.Button_Plus.Text = '+';
            app.Button_Multiply = uibutton(app.GridLayout, 'push');
            app.Button_Multiply.ButtonPushedFcn = createCallbackFcn(app, @ButtonMultiplyPushed, true);
            app.Button_Multiply.Text = '*';
            app.Button_Division = uibutton(app.GridLayout, 'push');
            app.Button_Division.ButtonPushedFcn = createCallbackFcn(app, @ButtonDividePushed, true);
            app.Button_Division.Text = '/';
            app.Button_Comma = uibutton(app.GridLayout, 'push');
            app.Button_Comma.ButtonPushedFcn = createCallbackFcn(app, @ButtonCommaPushed, true);
            app.Button_Comma.Text = ',';
            app.Button_Backspace = uibutton(app.GridLayout, 'push');
            app.Button_Backspace.ButtonPushedFcn = createCallbackFcn(app, @Button_BackspacePushed, true);
            app.Button_Backspace.Text = '←';
            app.Button_Openbar = uibutton(app.GridLayout, 'push');
            app.Button_Openbar.ButtonPushedFcn = createCallbackFcn(app, @ButtonOpenbarPushed, true);
            app.Button_Openbar.Text = '(';
            app.Button_Closebar = uibutton(app.GridLayout, 'push');
            app.Button_Closebar.ButtonPushedFcn = createCallbackFcn(app, @ButtonClosebarPushed, true);
            app.Button_Closebar.Text = ')';
            app.Button_Square = uibutton(app.GridLayout, 'push');
            app.Button_Square.ButtonPushedFcn = createCallbackFcn(app, @ButtonSquarePushed, true);
            app.Button_Square.Text = 'x²';
            app.Button_Modulus = uibutton(app.GridLayout, 'push');
            app.Button_Modulus.ButtonPushedFcn = createCallbackFcn(app, @Button_ModulusPushed, true);
            app.Button_Modulus.Text = '%';
            app.Button_Power = uibutton(app.GridLayout, 'push');
            app.Button_Power.ButtonPushedFcn = createCallbackFcn(app, @ButtonPowerPushed, true);
            app.Button_Power.Text = 'xⁿ';
            app.Button_Minus = uibutton(app.GridLayout, 'push');
            app.Button_Minus.ButtonPushedFcn = createCallbackFcn(app, @ButtonMinusPushed, true);
            app.Button_Minus.Text = '-';
            app.Button_Sqrt = uibutton(app.GridLayout, 'push');
            app.Button_Sqrt.ButtonPushedFcn = createCallbackFcn(app, @ButtonSqrtPushed, true);
            app.Button_Sqrt.Text = '√';
            app.Button_1 = uibutton(app.GridLayout, 'push');
            app.Button_1.ButtonPushedFcn = createCallbackFcn(app, @Button1Pushed, true);
            app.Button_1.Text = '1';
            app.Tab_6 = uitab(app.TabGroup);
            app.Tab_6.Title = 'Σταθερές';
            app.GridLayout_2 = uigridlayout(app.Tab_6);
            app.Button_11 = uibutton(app.GridLayout_2, 'push');
            app.Button_11.ButtonPushedFcn = createCallbackFcn(app, @Button2Pushed, true);
            app.Button_11.Text = '2';
            app.Button_12 = uibutton(app.GridLayout_2, 'push');
            app.Button_12.ButtonPushedFcn = createCallbackFcn(app, @Button3Pushed, true);
            app.Button_12.Text = '3';
            app.Button_13 = uibutton(app.GridLayout_2, 'push');
            app.Button_13.ButtonPushedFcn = createCallbackFcn(app, @Button4Pushed, true);
            app.Button_13.Text = '4';
            app.Button_14 = uibutton(app.GridLayout_2, 'push');
            app.Button_14.ButtonPushedFcn = createCallbackFcn(app, @Button5Pushed, true);
            app.Button_14.Text = '5';
            app.Button_15 = uibutton(app.GridLayout_2, 'push');
            app.Button_15.ButtonPushedFcn = createCallbackFcn(app, @Button6Pushed, true);
            app.Button_15.Text = '6';
            app.Button_16 = uibutton(app.GridLayout_2, 'push');
            app.Button_16.ButtonPushedFcn = createCallbackFcn(app, @Button7Pushed, true);
            app.Button_16.Text = '7';
            app.Button_17 = uibutton(app.GridLayout_2, 'push');
            app.Button_17.ButtonPushedFcn = createCallbackFcn(app, @Button8Pushed, true);
            app.Button_17.Text = '8';
            app.Button_18 = uibutton(app.GridLayout_2, 'push');
            app.Button_18.ButtonPushedFcn = createCallbackFcn(app, @Button9Pushed, true);
            app.Button_18.Text = '9';
            app.Button_19 = uibutton(app.GridLayout_2, 'push');
            app.Button_19.ButtonPushedFcn = createCallbackFcn(app, @Button0Pushed, true);
            app.Button_19.Text = '0';
            app.CButton_5 = uibutton(app.GridLayout_2, 'push');
            app.CButton_5.ButtonPushedFcn = createCallbackFcn(app, @ButtonClearPushed, true);
            app.CButton_5.Text = 'C';
            app.Button_Equals_3 = uibutton(app.GridLayout_2, 'push');
            app.Button_Equals_3.ButtonPushedFcn = createCallbackFcn(app, @Button_EqualsPushed, true);
            app.Button_Equals_3.Text = '=';
            app.Button_Plus_2 = uibutton(app.GridLayout_2, 'push');
            app.Button_Plus_2.ButtonPushedFcn = createCallbackFcn(app, @ButtonPlusPushed, true);
            app.Button_Plus_2.Text = '+';
            app.Button_Multiply_2 = uibutton(app.GridLayout_2, 'push');
            app.Button_Multiply_2.ButtonPushedFcn = createCallbackFcn(app, @ButtonMultiplyPushed, true);
            app.Button_Multiply_2.Text = '*';
            app.Button_Division_2 = uibutton(app.GridLayout_2, 'push');
            app.Button_Division_2.ButtonPushedFcn = createCallbackFcn(app, @ButtonDividePushed, true);
            app.Button_Division_2.Text = '/';
            app.Button_Comma_5 = uibutton(app.GridLayout_2, 'push');
            app.Button_Comma_5.ButtonPushedFcn = createCallbackFcn(app, @ButtonCommaPushed, true);
            app.Button_Comma_5.Text = ',';
            app.Button_Backspace_5 = uibutton(app.GridLayout_2, 'push');
            app.Button_Backspace_5.ButtonPushedFcn = createCallbackFcn(app, @Button_BackspacePushed, true);
            app.Button_Backspace_5.Text = '←';
            app.Button_Openbar_3 = uibutton(app.GridLayout_2, 'push');
            app.Button_Openbar_3.ButtonPushedFcn = createCallbackFcn(app, @ButtonOpenbarPushed, true);
            app.Button_Openbar_3.Text = '(';
            app.Button_Closebar_3 = uibutton(app.GridLayout_2, 'push');
            app.Button_Closebar_3.ButtonPushedFcn = createCallbackFcn(app, @ButtonClosebarPushed, true);
            app.Button_Closebar_3.Text = ')';
            app.Button_e_2 = uibutton(app.GridLayout_2, 'push');
            app.Button_e_2.ButtonPushedFcn = createCallbackFcn(app, @Button_e_2Pushed, true);
            app.Button_e_2.Text = 'e';
            app.Button_g_2 = uibutton(app.GridLayout_2, 'push');
            app.Button_g_2.ButtonPushedFcn = createCallbackFcn(app, @Button_g_2Pushed, true);
            app.Button_g_2.Text = 'g';
            app.Button_c_2 = uibutton(app.GridLayout_2, 'push');
            app.Button_c_2.ButtonPushedFcn = createCallbackFcn(app, @Button_c_2Pushed, true);
            app.Button_c_2.Text = 'c';
            app.Button_Minus_5 = uibutton(app.GridLayout_2, 'push');
            app.Button_Minus_5.ButtonPushedFcn = createCallbackFcn(app, @ButtonMinusPushed, true);
            app.Button_Minus_5.Text = '-';
            app.Button_pi_2 = uibutton(app.GridLayout_2, 'push');
            app.Button_pi_2.ButtonPushedFcn = createCallbackFcn(app, @Button_pi_2Pushed, true);
            app.Button_pi_2.Text = 'pi';
            app.Button_20 = uibutton(app.GridLayout_2, 'push');
            app.Button_20.ButtonPushedFcn = createCallbackFcn(app, @Button1Pushed, true);
            app.Button_20.Text = '1';
            app.Button_F_4 = uibutton(app.GridLayout_2, 'push');
            app.Button_F_4.ButtonPushedFcn = createCallbackFcn(app, @Button_F_4Pushed, true);
            app.Button_F_4.Text = 'F';
            app.Tab_2 = uitab(app.TabGroup);
            app.Tab_2.Title = 'Προχωρημένες';
            app.GridLayout2 = uigridlayout(app.Tab_2);
            app.Button_1_1 = uibutton(app.GridLayout2, 'push');
            app.Button_1_1.ButtonPushedFcn = createCallbackFcn(app, @Button1Pushed, true);
            app.Button_1_1.Text = '1';
            app.Button_1_2 = uibutton(app.GridLayout2, 'push');
            app.Button_1_2.ButtonPushedFcn = createCallbackFcn(app, @Button2Pushed, true);
            app.Button_1_2.Text = '2';
            app.Button_1_3 = uibutton(app.GridLayout2, 'push');
            app.Button_1_3.ButtonPushedFcn = createCallbackFcn(app, @Button3Pushed, true);
            app.Button_1_3.Text = '3';
            app.Button_1_4 = uibutton(app.GridLayout2, 'push');
            app.Button_1_4.ButtonPushedFcn = createCallbackFcn(app, @Button4Pushed, true);
            app.Button_1_4.Text = '4';
            app.Button_1_5 = uibutton(app.GridLayout2, 'push');
            app.Button_1_5.ButtonPushedFcn = createCallbackFcn(app, @Button5Pushed, true);
            app.Button_1_5.Text = '5';
            app.Button_1_6 = uibutton(app.GridLayout2, 'push');
            app.Button_1_6.ButtonPushedFcn = createCallbackFcn(app, @Button6Pushed, true);
            app.Button_1_6.Text = '6';
            app.Button_1_7 = uibutton(app.GridLayout2, 'push');
            app.Button_1_7.ButtonPushedFcn = createCallbackFcn(app, @Button7Pushed, true);
            app.Button_1_7.Text = '7';
            app.Button_1_8 = uibutton(app.GridLayout2, 'push');
            app.Button_1_8.ButtonPushedFcn = createCallbackFcn(app, @Button8Pushed, true);
            app.Button_1_8.Text = '8';
            app.Button_1_9 = uibutton(app.GridLayout2, 'push');
            app.Button_1_9.ButtonPushedFcn = createCallbackFcn(app, @Button9Pushed, true);
            app.Button_1_9.Text = '9';
            app.Button_2_0 = uibutton(app.GridLayout2, 'push');
            app.Button_2_0.ButtonPushedFcn = createCallbackFcn(app, @Button0Pushed, true);
            app.Button_2_0.Text = '0';
            app.Button_Equals_2 = uibutton(app.GridLayout2, 'push');
            app.Button_Equals_2.ButtonPushedFcn = createCallbackFcn(app, @Button_EqualsPushed, true);
            app.Button_Equals_2.Text = '=';
            app.CButton_2 = uibutton(app.GridLayout2, 'push');
            app.CButton_2.ButtonPushedFcn = createCallbackFcn(app, @ButtonClearPushed, true);
            app.CButton_2.Text = 'C';
            app.Button_Comma_2 = uibutton(app.GridLayout2, 'push');
            app.Button_Comma_2.ButtonPushedFcn = createCallbackFcn(app, @ButtonCommaPushed, true);
            app.Button_Comma_2.Text = ',';
            app.Button_Openbar_2 = uibutton(app.GridLayout2, 'push');
            app.Button_Openbar_2.ButtonPushedFcn = createCallbackFcn(app, @ButtonOpenbarPushed, true);
            app.Button_Openbar_2.Text = '(';
            app.Button_Closebar_2 = uibutton(app.GridLayout2, 'push');
            app.Button_Closebar_2.ButtonPushedFcn = createCallbackFcn(app, @ButtonClosebarPushed, true);
            app.Button_Closebar_2.Text = ')';
            app.Button_Backspace_2 = uibutton(app.GridLayout2, 'push');
            app.Button_Backspace_2.ButtonPushedFcn = createCallbackFcn(app, @Button_BackspacePushed, true);
            app.Button_Backspace_2.Text = '←';
            app.Button_Factorial = uibutton(app.GridLayout2, 'push');
            app.Button_Factorial.ButtonPushedFcn = createCallbackFcn(app, @Button_FactorialPushed2, true);
            app.Button_Factorial.Text = 'x!';
            app.PrimeFactorsButton = uibutton(app.GridLayout2, 'push');
            app.PrimeFactorsButton.ButtonPushedFcn = createCallbackFcn(app, @PrimeFactorsButtonPushed, true);
            app.PrimeFactorsButton.Text = {'Prime'; 'Factors'};
            app.DivisorsxButton = uibutton(app.GridLayout2, 'push');
            app.DivisorsxButton.ButtonPushedFcn = createCallbackFcn(app, @DivisorsxButtonPushed, true);
            app.DivisorsxButton.Text = 'Divisors(x)';
            app.LCMButton = uibutton(app.GridLayout2, 'push');
            app.LCMButton.ButtonPushedFcn = createCallbackFcn(app, @LCMButtonPushed, true);
            app.LCMButton.Text = 'LCM';
            app.GCDButton = uibutton(app.GridLayout2, 'push');
            app.GCDButton.ButtonPushedFcn = createCallbackFcn(app, @GCDButtonPushed, true);
            app.GCDButton.Text = 'GCD';
            app.exButton = uibutton(app.GridLayout2, 'push');
            app.exButton.ButtonPushedFcn = createCallbackFcn(app, @exButtonPushed, true);
            app.exButton.Text = 'e^x';
            app.lnxButton = uibutton(app.GridLayout2, 'push');
            app.lnxButton.ButtonPushedFcn = createCallbackFcn(app, @lnxButtonPushed, true);
            app.lnxButton.Text = 'ln (x)';
            app.logxButton = uibutton(app.GridLayout2, 'push');
            app.logxButton.ButtonPushedFcn = createCallbackFcn(app, @logxButtonPushed, true);
            app.logxButton.Text = 'log₁₀(x)';
            app.axbxc0Button = uibutton(app.GridLayout2, 'push');
            app.axbxc0Button.ButtonPushedFcn = createCallbackFcn(app, @axbxc0ButtonPushed, true);
            app.axbxc0Button.Text = 'ax² + bx + c = 0';
            app.Button_Minus_2 = uibutton(app.GridLayout2, 'push');
            app.Button_Minus_2.ButtonPushedFcn = createCallbackFcn(app, @ButtonMinusPushed, true);
            app.Button_Minus_2.Text = '-';
            app.Tab_3 = uitab(app.TabGroup);
            app.Tab_3.Title = 'Τριγονομετρία';
            app.GridLayout3 = uigridlayout(app.Tab_3);
            app.ExpressionLabelBasic_3 = uilabel(app.GridLayout3);
            app.ExpressionLabelBasic_3.Text = '';
            app.Button_Backspace_3 = uibutton(app.GridLayout3, 'push');
            app.Button_Backspace_3.ButtonPushedFcn = createCallbackFcn(app, @Button_BackspacePushed, true);
            app.Button_Backspace_3.Text = '←';
            app.Button_2_1 = uibutton(app.GridLayout3, 'push');
            app.Button_2_1.ButtonPushedFcn = createCallbackFcn(app, @Button1Pushed, true);
            app.Button_2_1.Text = '1';
            app.Button_2_2 = uibutton(app.GridLayout3, 'push');
            app.Button_2_2.ButtonPushedFcn = createCallbackFcn(app, @Button2Pushed, true);
            app.Button_2_2.Text = '2';
            app.Button_2_3 = uibutton(app.GridLayout3, 'push');
            app.Button_2_3.ButtonPushedFcn = createCallbackFcn(app, @Button3Pushed, true);
            app.Button_2_3.Text = '3';
            app.Button_2_4 = uibutton(app.GridLayout3, 'push');
            app.Button_2_4.ButtonPushedFcn = createCallbackFcn(app, @Button4Pushed, true);
            app.Button_2_4.Text = '4';
            app.Button_2_5 = uibutton(app.GridLayout3, 'push');
            app.Button_2_5.ButtonPushedFcn = createCallbackFcn(app, @Button5Pushed, true);
            app.Button_2_5.Text = '5';
            app.Button_2_6 = uibutton(app.GridLayout3, 'push');
            app.Button_2_6.ButtonPushedFcn = createCallbackFcn(app, @Button6Pushed, true);
            app.Button_2_6.Text = '6';
            app.Button_2_7 = uibutton(app.GridLayout3, 'push');
            app.Button_2_7.ButtonPushedFcn = createCallbackFcn(app, @Button7Pushed, true);
            app.Button_2_7.Text = '7';
            app.Button_2_8 = uibutton(app.GridLayout3, 'push');
            app.Button_2_8.ButtonPushedFcn = createCallbackFcn(app, @Button8Pushed, true);
            app.Button_2_8.Text = '8';
            app.Button_2_9 = uibutton(app.GridLayout3, 'push');
            app.Button_2_9.ButtonPushedFcn = createCallbackFcn(app, @Button9Pushed, true);
            app.Button_2_9.Text = '9';
            app.CButton_3 = uibutton(app.GridLayout3, 'push');
            app.CButton_3.ButtonPushedFcn = createCallbackFcn(app, @ButtonClearPushed, true);
            app.CButton_3.Text = 'C';
            app.Button_2__0 = uibutton(app.GridLayout3, 'push');
            app.Button_2__0.ButtonPushedFcn = createCallbackFcn(app, @Button0Pushed, true);
            app.Button_2__0.Text = '0';
            app.Button_Comma_3 = uibutton(app.GridLayout3, 'push');
            app.Button_Comma_3.ButtonPushedFcn = createCallbackFcn(app, @ButtonCommaPushed, true);
            app.Button_Comma_3.Text = ',';
            app.sinxButton = uibutton(app.GridLayout3, 'push');
            app.sinxButton.ButtonPushedFcn = createCallbackFcn(app, @sinxButtonPushed, true);
            app.sinxButton.Text = {'sin(x)'; 'Ημίτονο'};
            app.cosxButton = uibutton(app.GridLayout3, 'push');
            app.cosxButton.ButtonPushedFcn = createCallbackFcn(app, @cosxButtonPushed, true);
            app.cosxButton.Text = {'cos(x)'; 'Συνημίτονο'};
            app.tanxButton = uibutton(app.GridLayout3, 'push');
            app.tanxButton.ButtonPushedFcn = createCallbackFcn(app, @tanxButtonPushed, true);
            app.tanxButton.Text = {'tan(x)'; 'Εφαπτομένη'};
            app.cotxButton = uibutton(app.GridLayout3, 'push');
            app.cotxButton.ButtonPushedFcn = createCallbackFcn(app, @cotxButtonPushed, true);
            app.cotxButton.Text = {'cot(x)'; 'Συνεφαπτομένη'};
            app.secxButton = uibutton(app.GridLayout3, 'push');
            app.secxButton.ButtonPushedFcn = createCallbackFcn(app, @secxButtonPushed, true);
            app.secxButton.Text = {'sec(x)'; 'Τέμνουσα'};
            app.cscxButton = uibutton(app.GridLayout3, 'push');
            app.cscxButton.ButtonPushedFcn = createCallbackFcn(app, @cscxButtonPushed, true);
            app.cscxButton.Text = {'csc(x)'; 'Συντέμνουσα'};
            app.asinxButton = uibutton(app.GridLayout3, 'push');
            app.asinxButton.ButtonPushedFcn = createCallbackFcn(app, @asinxButtonPushed, true);
            app.asinxButton.Text = {'asin(x)'; 'Τόξο '; 'Ημιτόνου'};
            app.acosxButton = uibutton(app.GridLayout3, 'push');
            app.acosxButton.ButtonPushedFcn = createCallbackFcn(app, @acosxButtonPushed, true);
            app.acosxButton.Text = {'acos(x)'; 'Τόξο'; 'Συνημιτόνου'};
            app.acotxButton = uibutton(app.GridLayout3, 'push');
            app.acotxButton.ButtonPushedFcn = createCallbackFcn(app, @acotxButtonPushed, true);
            app.acotxButton.Text = {'acot(x)'; 'Τόξο'; 'Συνεφαπτομένης'};
            app.asecxButton = uibutton(app.GridLayout3, 'push');
            app.asecxButton.ButtonPushedFcn = createCallbackFcn(app, @asecxButtonPushed, true);
            app.asecxButton.Text = {'asec(x)'; 'Τόξο'; 'Τέμνουσας'};
            app.Button_Minus_3 = uibutton(app.GridLayout3, 'push');
            app.Button_Minus_3.ButtonPushedFcn = createCallbackFcn(app, @ButtonMinusPushed, true);
            app.Button_Minus_3.Text = '-';
            app.acscxButton = uibutton(app.GridLayout3, 'push');
            app.acscxButton.ButtonPushedFcn = createCallbackFcn(app, @acscxButtonPushed, true);
            app.acscxButton.Text = {'acsc(x)'; 'Τόξο'; 'Συντέμνουσας'};
            app.atanxButton = uibutton(app.GridLayout3, 'push');
            app.atanxButton.ButtonPushedFcn = createCallbackFcn(app, @atanxButtonPushed, true);
            app.atanxButton.Text = {'atan(x)'; 'Τόξο'; 'Εφαπτομένης'};
            app.Tab_4 = uitab(app.TabGroup);
            app.Tab_4.Title = 'Υπερβολικά';
            app.GridLayout4 = uigridlayout(app.Tab_4);
            app.ExpressionLabelBasic_4 = uilabel(app.GridLayout4);
            app.ExpressionLabelBasic_4.Text = '';
            app.Button_Backspace_4 = uibutton(app.GridLayout4, 'push');
            app.Button_Backspace_4.ButtonPushedFcn = createCallbackFcn(app, @Button_BackspacePushed, true);
            app.Button_Backspace_4.Text = '←';
            app.Button_3_1 = uibutton(app.GridLayout4, 'push');
            app.Button_3_1.ButtonPushedFcn = createCallbackFcn(app, @Button1Pushed, true);
            app.Button_3_1.Text = '1';
            app.Button_3_2 = uibutton(app.GridLayout4, 'push');
            app.Button_3_2.ButtonPushedFcn = createCallbackFcn(app, @Button2Pushed, true);
            app.Button_3_2.Text = '2';
            app.Button_3_3 = uibutton(app.GridLayout4, 'push');
            app.Button_3_3.ButtonPushedFcn = createCallbackFcn(app, @Button3Pushed, true);
            app.Button_3_3.Text = '3';
            app.Button_3_4 = uibutton(app.GridLayout4, 'push');
            app.Button_3_4.ButtonPushedFcn = createCallbackFcn(app, @Button4Pushed, true);
            app.Button_3_4.Text = '4';
            app.Button_3_5 = uibutton(app.GridLayout4, 'push');
            app.Button_3_5.ButtonPushedFcn = createCallbackFcn(app, @Button5Pushed, true);
            app.Button_3_5.Text = '5';
            app.Button_3_6 = uibutton(app.GridLayout4, 'push');
            app.Button_3_6.ButtonPushedFcn = createCallbackFcn(app, @Button6Pushed, true);
            app.Button_3_6.Text = '6';
            app.Button_3_7 = uibutton(app.GridLayout4, 'push');
            app.Button_3_7.ButtonPushedFcn = createCallbackFcn(app, @Button7Pushed, true);
            app.Button_3_7.Text = '7';
            app.Button_3_8 = uibutton(app.GridLayout4, 'push');
            app.Button_3_8.ButtonPushedFcn = createCallbackFcn(app, @Button8Pushed, true);
            app.Button_3_8.Text = '8';
            app.Button_3_9 = uibutton(app.GridLayout4, 'push');
            app.Button_3_9.ButtonPushedFcn = createCallbackFcn(app, @Button9Pushed, true);
            app.Button_3_9.Text = '9';
            app.CButton_4 = uibutton(app.GridLayout4, 'push');
            app.CButton_4.ButtonPushedFcn = createCallbackFcn(app, @ButtonClearPushed, true);
            app.CButton_4.Text = 'C';
            app.Button_3_0 = uibutton(app.GridLayout4, 'push');
            app.Button_3_0.ButtonPushedFcn = createCallbackFcn(app, @Button0Pushed, true);
            app.Button_3_0.Text = '0';
            app.Button_Comma_4 = uibutton(app.GridLayout4, 'push');
            app.Button_Comma_4.ButtonPushedFcn = createCallbackFcn(app, @ButtonCommaPushed, true);
            app.Button_Comma_4.Text = ',';
            app.sinhxButton = uibutton(app.GridLayout4, 'push');
            app.sinhxButton.ButtonPushedFcn = createCallbackFcn(app, @sinhxButtonPushed, true);
            app.sinhxButton.Text = {'sinh(x)'; 'Υπερβολικό'; 'Ημίτονο'};
            app.coshxButton = uibutton(app.GridLayout4, 'push');
            app.coshxButton.ButtonPushedFcn = createCallbackFcn(app, @coshxButtonPushed, true);
            app.coshxButton.Text = {'cosh(x)'; 'Υπερβολικό'; 'Συνημίτονο'};
            app.tanhxButton = uibutton(app.GridLayout4, 'push');
            app.tanhxButton.ButtonPushedFcn = createCallbackFcn(app, @tanhxButtonPushed, true);
            app.tanhxButton.Text = {'tanh(x)'; 'Υπερβολική'; 'Εφαπτομένη'};
            app.cothxButton = uibutton(app.GridLayout4, 'push');
            app.cothxButton.ButtonPushedFcn = createCallbackFcn(app, @cothxButtonPushed, true);
            app.cothxButton.Text = {'coth(x)'; 'Υπερβολική'; 'Συνεφαπτομένη'};
            app.sechxButton = uibutton(app.GridLayout4, 'push');
            app.sechxButton.ButtonPushedFcn = createCallbackFcn(app, @sechxButtonPushed, true);
            app.sechxButton.Text = {'sech(x)'; 'Υπερβολική'; 'Τέμνουσα'};
            app.cschxButton = uibutton(app.GridLayout4, 'push');
            app.cschxButton.ButtonPushedFcn = createCallbackFcn(app, @cschxButtonPushed, true);
            app.cschxButton.Text = {'csch(x)'; 'Υπερβολική'; 'Συντέμνουσα'};
            app.asinhxButton = uibutton(app.GridLayout4, 'push');
            app.asinhxButton.ButtonPushedFcn = createCallbackFcn(app, @asinhxButtonPushed, true);
            app.asinhxButton.Text = {'asinh(x)'; 'Τόξο'; 'Υπερβολικού'; 'Ημιτόνου'};
            app.acoshxButton = uibutton(app.GridLayout4, 'push');
            app.acoshxButton.ButtonPushedFcn = createCallbackFcn(app, @acoshxButtonPushed, true);
            app.acoshxButton.Text = {'acosh(x)'; 'Τόξο'; 'Υπερβολικού'; 'Συνημιτόνου'};
            app.atanhxButton = uibutton(app.GridLayout4, 'push');
            app.atanhxButton.ButtonPushedFcn = createCallbackFcn(app, @atanhxButtonPushed, true);
            app.atanhxButton.Text = {'atanh(x)'; 'Τόξο'; 'Υπερβολικής'; 'Εφαπτομένης'};
            app.acothxButton = uibutton(app.GridLayout4, 'push');
            app.acothxButton.ButtonPushedFcn = createCallbackFcn(app, @acothxButtonPushed, true);
            app.acothxButton.Text = {'acoth(x)'; 'Τόξο'; 'Υπερβολικής'; 'Συνεφαπτομένης'};
            app.asechxButton = uibutton(app.GridLayout4, 'push');
            app.asechxButton.ButtonPushedFcn = createCallbackFcn(app, @asechxButtonPushed, true);
            app.asechxButton.Text = {'asech(x)'; 'Τόξο'; 'Υπερβολικής'; 'Τέμνουσας'};
            app.Button_Minus_4 = uibutton(app.GridLayout4, 'push');
            app.Button_Minus_4.ButtonPushedFcn = createCallbackFcn(app, @ButtonMinusPushed, true);
            app.Button_Minus_4.Text = '-';
            app.acschxButton = uibutton(app.GridLayout4, 'push');
            app.acschxButton.ButtonPushedFcn = createCallbackFcn(app, @acschxButtonPushed, true);
            app.acschxButton.Text = {'acsch(x)'; 'Τόξο'; 'Υπερβολικής'; 'Συντέμνουσας'};
            app.ExpressionLabelBasic = uilabel(app.UIFigure);
            app.ExpressionLabelBasic.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end